classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        UIAxes           matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes3          matlab.ui.control.UIAxes
        UIAxes4          matlab.ui.control.UIAxes
        MakeplotsButton  matlab.ui.control.Button
        Image            matlab.ui.control.Image
        RSpinnerLabel    matlab.ui.control.Label
        RSpinner         matlab.ui.control.Spinner
        LSpinnerLabel    matlab.ui.control.Label
        LSpinner         matlab.ui.control.Spinner
        CSpinnerLabel    matlab.ui.control.Label
        CSpinner         matlab.ui.control.Spinner
        VinSpinnerLabel  matlab.ui.control.Label
        VinSpinner       matlab.ui.control.Spinner
        Label            matlab.ui.control.Label
        Knob             matlab.ui.control.DiscreteKnob
        Knob_2Label      matlab.ui.control.Label
        Knob_2           matlab.ui.control.DiscreteKnob
        Knob_3Label      matlab.ui.control.Label
        Knob_3           matlab.ui.control.DiscreteKnob
        Knob_4Label      matlab.ui.control.Label
        Knob_4           matlab.ui.control.DiscreteKnob
        TSpinnerLabel    matlab.ui.control.Label
        TSpinner         matlab.ui.control.Spinner
        fSpinnerLabel    matlab.ui.control.Label
        fSpinner         matlab.ui.control.Spinner
        DrawplotsButton  matlab.ui.control.Button
        StatusLabel      matlab.ui.control.Label
        Label_2          matlab.ui.control.Label
        Label_3          matlab.ui.control.Label
        mHLabel          matlab.ui.control.Label
        FLabel           matlab.ui.control.Label
        VLabel           matlab.ui.control.Label
        msLabel          matlab.ui.control.Label
        HZLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        t=[];
        I=[];
        Vc=[];
        XL=[];
        XC=[];
        X=[];
        R = 0;               
        L = 0;           
        C = 0;            
        Vi = 0;
        T = 0;
        F=0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MakeplotsButton.Enable = 'off';
            app.DrawplotsButton.Enable = 'off';
            app.UIAxes.Visible = 'off';
            app.UIAxes2.Visible = 'off';
            app.UIAxes3.Visible = 'off';
            app.UIAxes4.Visible = 'off';
            app.Knob.Enable = 'off';
            app.Knob_2.Enable = 'off';
            app.Knob_3.Enable = 'off';
            app.Knob_4.Enable = 'off';
            app.fSpinner.Enable = 'off';
            app.Label_2.Text = 'Czekanie na podanie parametrów układu';
        end

        % Value changed function: RSpinner
        function RSpinnerValueChanged(app, event)
            app.R=app.RSpinner.Value;
            if (app.RSpinner.Value == 0 || app.LSpinner.Value == 0 || app.CSpinner.Value == 0 || app.TSpinner.Value == 0||  app.VinSpinner.Value == 0 )
                app.MakeplotsButton.Enable = 'off';
                app.Knob.Enable = 'off';
                app.Knob_2.Enable = 'off';
                app.Knob_3.Enable = 'off';
                app.Knob_4.Enable = 'off';
            end
            if (app.RSpinner.Value~=0 && app.LSpinner.Value ~= 0 && app.CSpinner.Value ~= 0 && app.TSpinner.Value ~= 0 &&  app.VinSpinner.Value ~= 0 )
                app.MakeplotsButton.Enable = 'on';
                app.Knob.Enable = 'on';
                app.Knob_2.Enable = 'on';
                app.Knob_3.Enable = 'on';
                app.Knob_4.Enable = 'on';
                app.Label_2.Text = 'Podano wartości patametrów .Czekanie na wybór wykresów';
            end
        end

        % Value changed function: LSpinner
        function LSpinnerValueChanged(app, event)
            app.L=app.LSpinner.Value*0.001;
            if (app.RSpinner.Value == 0 || app.LSpinner.Value == 0 || app.CSpinner.Value == 0 || app.TSpinner.Value == 0||  app.VinSpinner.Value == 0 )
                app.MakeplotsButton.Enable = 'off';
                app.Knob.Enable = 'off';
                app.Knob_2.Enable = 'off';
                app.Knob_3.Enable = 'off';
                app.Knob_4.Enable = 'off';
            end
            if (app.RSpinner.Value~=0 && app.LSpinner.Value ~= 0 && app.CSpinner.Value ~= 0 && app.TSpinner.Value ~= 0 &&  app.VinSpinner.Value ~= 0 )
                app.MakeplotsButton.Enable = 'on';
                app.Knob.Enable = 'on';
                app.Knob_2.Enable = 'on';
                app.Knob_3.Enable = 'on';
                app.Knob_4.Enable = 'on';
                app.Label_2.Text = 'Podano wartości patametrów .Czekanie na wybór wykresów';
            end
        end

        % Value changed function: CSpinner
        function CSpinnerValueChanged(app, event)
           app.C = app.CSpinner.Value*0.000001;
            if (app.RSpinner.Value == 0 || app.LSpinner.Value == 0 || app.CSpinner.Value == 0 || app.TSpinner.Value == 0|| app.VinSpinner.Value == 0 )
                app.MakeplotsButton.Enable = 'off';
                app.Knob.Enable = 'off';
                app.Knob_2.Enable = 'off';
                app.Knob_3.Enable = 'off';
                app.Knob_4.Enable = 'off';
            end
            if (app.RSpinner.Value~=0 && app.LSpinner.Value ~= 0 && app.CSpinner.Value ~= 0 && app.TSpinner.Value ~= 0 &&  app.VinSpinner.Value ~= 0 )
                app.MakeplotsButton.Enable = 'on';
                app.Knob.Enable = 'on';
                app.Knob_2.Enable = 'on';
                app.Knob_3.Enable = 'on';
                app.Knob_4.Enable = 'on';
                app.Label_2.Text = 'Podano wartości patametrów .Czekanie na wybór wykresów';
            end
        end

        % Value changed function: VinSpinner
        function VinSpinnerValueChanged(app, event)
            app.Vi = app.VinSpinner.Value;
            if (app.RSpinner.Value == 0 || app.LSpinner.Value == 0 || app.CSpinner.Value == 0 || app.TSpinner.Value == 0||  app.VinSpinner.Value == 0 )
                app.MakeplotsButton.Enable = 'off';
                app.Knob.Enable = 'off';
                app.Knob_2.Enable = 'off';
                app.Knob_3.Enable = 'off';
                app.Knob_4.Enable = 'off';
            end
            if (app.RSpinner.Value~=0 && app.LSpinner.Value ~= 0 && app.CSpinner.Value ~= 0 && app.TSpinner.Value ~= 0 && app.VinSpinner.Value ~= 0 )
                app.MakeplotsButton.Enable = 'on';
                app.Knob.Enable = 'on';
                app.Knob_2.Enable = 'on';
                app.Knob_3.Enable = 'on';
                app.Knob_4.Enable = 'on';
                app.Label_2.Text = 'Podano wartości patametrów .Czekanie na wybór wykresów';
            end
        end

        % Value changed function: TSpinner
        function TSpinnerValueChanged(app, event)
            app.T = app.TSpinner.Value*0.001;
            if (app.RSpinner.Value == 0 || app.LSpinner.Value == 0 || app.CSpinner.Value == 0 || app.TSpinner.Value == 0||  app.VinSpinner.Value == 0 )
                app.MakeplotsButton.Enable = 'off';
                app.Knob.Enable = 'off';
                app.Knob_2.Enable = 'off';
                app.Knob_3.Enable = 'off';
                app.Knob_4.Enable = 'off';
            end
            if (app.RSpinner.Value~=0 && app.LSpinner.Value ~= 0 && app.CSpinner.Value ~= 0 && app.TSpinner.Value ~= 0 &&  app.VinSpinner.Value ~= 0 )
                app.MakeplotsButton.Enable = 'on';
                app.Knob.Enable = 'on';
                app.Knob_2.Enable = 'on';
                app.Knob_3.Enable = 'on';
                app.Knob_4.Enable = 'on';
                app.Label_2.Text = 'Podano wartości patametrów .Czekanie na wybór wykresów';
            end
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            if (app.Knob.Value == "Off" && app.Knob_2.Value == "Off" && app.Knob_3.Value == "Off" && app.Knob_4.Value == "Off")
                app.MakeplotsButton.Enable = 'off';
            end
            if (app.Knob.Value == "X(f)" || app.Knob_2.Value == "X(f)" || app.Knob_3.Value == "X(f)" || app.Knob_4.Value == "X(f)")
                app.fSpinner.Enable = 'on';
                app.MakeplotsButton.Enable = 'off';
                app.Label_2.Text = 'wybrano wykres X(f) wprowadź wartość końcową f ';
            end
            if (app.Knob.Value ~= "Off" || app.Knob_2.Value ~= "Off" || app.Knob_3.Value ~= "Off" || app.Knob_4.Value ~= "Off")
                app.MakeplotsButton.Enable = 'on';
                app.Label_2.Text = 'wybrano wykresy naciśnij Make plots aby zrobić wykresy';
            end
        end

        % Value changed function: Knob_2
        function Knob_2ValueChanged(app, event)
            if (app.Knob.Value == "Off" && app.Knob_2.Value == "Off" && app.Knob_3.Value == "Off" && app.Knob_4.Value == "Off")
                app.MakeplotsButton.Enable = 'off';
            end
            if (app.Knob.Value == "X(f)" || app.Knob_2.Value == "X(f)" || app.Knob_3.Value == "X(f)" || app.Knob_4.Value == "X(f)")
                app.fSpinner.Enable = 'on';
                app.MakeplotsButton.Enable = 'off';
                app.Label_2.Text = 'wybrano wykres X(f) wprowadź wartość końcową f ';
            end
            if (app.Knob.Value ~= "Off" || app.Knob_2.Value ~= "Off" || app.Knob_3.Value ~= "Off" || app.Knob_4.Value ~= "Off")
                app.MakeplotsButton.Enable = 'on';
                app.Label_2.Text = 'wybrano wykresy naciśnij Make plots aby zrobić wykresy';
            end
        end

        % Value changed function: Knob_3
        function Knob_3ValueChanged(app, event)
             if (app.Knob.Value == "Off" && app.Knob_2.Value == "Off" && app.Knob_3.Value == "Off" && app.Knob_4.Value == "Off")
                app.MakeplotsButton.Enable = 'off';
            end
            if (app.Knob.Value == "X(f)" || app.Knob_2.Value == "X(f)" || app.Knob_3.Value == "X(f)" || app.Knob_4.Value == "X(f)")
                app.fSpinner.Enable = 'on';
                app.MakeplotsButton.Enable = 'off';
                app.Label_2.Text = 'wybrano wykres X(f) wprowadź wartość końcową f ';
            end
            if (app.Knob.Value ~= "Off" || app.Knob_2.Value ~= "Off" || app.Knob_3.Value ~= "Off" || app.Knob_4.Value ~= "Off")
                app.MakeplotsButton.Enable = 'on';
                app.Label_2.Text = 'wybrano wykresy naciśnij Make plots aby zrobić wykresy';
            end
        end

        % Value changed function: Knob_4
        function Knob_4ValueChanged(app, event)
             if (app.Knob.Value == "Off" && app.Knob_2.Value == "Off" && app.Knob_3.Value == "Off" && app.Knob_4.Value == "Off")
                app.MakeplotsButton.Enable = 'off';
            end
            if (app.Knob.Value == "X(f)" || app.Knob_2.Value == "X(f)" || app.Knob_3.Value == "X(f)" || app.Knob_4.Value == "X(f)")
                app.fSpinner.Enable = 'on';
                app.MakeplotsButton.Enable = 'off';
                app.Label_2.Text = 'wybrano wykres X(f) wprowadź wartość końcową f ';
            end
            if (app.Knob.Value ~= "Off" || app.Knob_2.Value ~= "Off" || app.Knob_3.Value ~= "Off" || app.Knob_4.Value ~= "Off")
                app.MakeplotsButton.Enable = 'on';
                app.Label_2.Text = 'wybrano wykresy naciśnij Make plots aby zrobić wykresy';
            end
        end

        % Value changed function: fSpinner
        function fSpinnerValueChanged(app, event)
            app.F=0:0.01:app.fSpinner.Value;
            if (app.fSpinner.Value == 0 ||app.RSpinner.Value == 0|| app.LSpinner.Value == 0 || app.CSpinner.Value == 0 || app.TSpinner.Value == 0|| app.VinSpinner.Value == 0 )
                app.MakeplotsButton.Enable = 'off';
            end
            if (app.fSpinner.Value ~= 0 && app.RSpinner.Value~=0 && app.LSpinner.Value ~= 0 && app.CSpinner.Value ~= 0 && app.TSpinner.Value ~= 0 && app.VinSpinner.Value ~= 0 )
                app.MakeplotsButton.Enable = 'on';
                app.Label_2.Text = 'Podano wartość patametru .Naciśnij Make plots aby zrobić wykresy';
            end
        end

        % Button pushed function: MakeplotsButton
        function MakeplotsButtonPushed(app, event)
            x0 =  [0; 0];      % warunki początkowe 
            tspan = [0, app.T];
             function y = RLC(t,x,R,L,C,Vi)
                % y(1) = d (Ii);    y(2) = d (Vc);
                %        /dt               /dt
                y = [-R/L, -1/L; 1/C, 0]*x + [1/L; 0]*Vi;
            end
            [app.t, x] = ode45(@(t,x) RLC(t,x,app.R,app.L,app.C,app.Vi), tspan, x0); 
            
            app.I = x(:,1);    
            app.Vc = x(:,2);
           
            app.XL = 2*pi*app.F*app.L;
            app.XC = 1./(2*pi*app.F*app.C);
            app.X = app.XL - app.XC;
            app.RSpinner.Enable='off';
            app.LSpinner.Enable='off';
            app.CSpinner.Enable='off';
            app.VinSpinner.Enable='off';
            app.TSpinner.Enable='off';
            app.fSpinner.Enable='off';
            app.DrawplotsButton.Enable='on';
            app.Label_2.Text = 'Obliczono .Naciśnij Draw plots aby znarysować wykresy';
        end

        % Button pushed function: DrawplotsButton
        function DrawplotsButtonPushed(app, event)
            if (app.Knob.Value=="Uc(T)")
                app.UIAxes.Visible = 'on';
                plot(app.UIAxes,app.t, app.Vc, 'r', 'linewidth',0.75);
                title(app.UIAxes,'Napięcie na kondensatorze'); 
                xlabel(app.UIAxes,'T(s)'); 
                ylabel(app.UIAxes,'U(V)');
            end
            if (app.Knob.Value=="I(T)")
                app.UIAxes.Visible = 'on';
                plot(app.UIAxes,app.t, app.I, 'r', 'linewidth',0.75);
                title(app.UIAxes,'Prąd w obwodzie'); 
                xlabel(app.UIAxes,'T(s)'); 
                ylabel(app.UIAxes,'I(A)');
            end
            if (app.Knob.Value=="Ur(T)")
                app.UIAxes.Visible = 'on';
                plot(app.UIAxes,app.t, app.I.*app.R, 'r', 'linewidth',0.75);
                title(app.UIAxes,'Napięcie na Rezystorze'); 
                xlabel(app.UIAxes,'T(s)'); 
                ylabel(app.UIAxes,'U(V)');
            end
            if (app.Knob.Value=="Ul(T)")
                app.UIAxes.Visible = 'on';
                plot(app.UIAxes,app.t, -app.Vc+app.Vi-app.I.*app.R, 'r', 'linewidth',0.75);
                title(app.UIAxes,'Napięcie na Cewce');
                xlabel(app.UIAxes,'T(s)'); 
                ylabel(app.UIAxes,'U(V)');
            end
            if (app.Knob.Value=="X(f)")
                app.UIAxes.Visible = 'on';
                plot(app.UIAxes,app.F, app.XL,app.F,app.XC,app.F, app.X);
                title(app.UIAxes,'Charakterystyka częstotliwościowa');
                xlabel(app.UIAxes,'f [Hz]'); 
                ylabel(app.UIAxes,'X [Ohm]');
                legend(app.UIAxes,'XL','XC','X');
                app.fSpinner.Enable='on';
            end
            if (app.Knob_2.Value=="Uc(T)")
                app.UIAxes2.Visible = 'on';
                plot(app.UIAxes2,app.t, app.Vc, 'r', 'linewidth',0.75);
                title(app.UIAxes2,'Napięcie na kondensatorze'); 
                xlabel(app.UIAxes2,'T(s)'); 
                ylabel(app.UIAxes2,'U(V)');
            end
            if (app.Knob_2.Value=="I(T)")
                app.UIAxes2.Visible = 'on';
                plot(app.UIAxes2,app.t, app.I, 'r', 'linewidth',0.75);
                title(app.UIAxes2,'Prąd w obwodzie'); 
                xlabel(app.UIAxes2,'T(s)'); 
                ylabel(app.UIAxes2,'I(A)');
            end
            if (app.Knob_2.Value=="Ur(T)")
                app.UIAxes2.Visible = 'on';
                plot(app.UIAxes2,app.t, app.I.*app.R, 'r', 'linewidth',0.75);
                title(app.UIAxes2,'Napięcie na Rezystorze'); 
                xlabel(app.UIAxes2,'T(s)'); 
                ylabel(app.UIAxes2,'U(V)');
            end
            if (app.Knob_2.Value=="Ul(T)")
                app.UIAxes2.Visible = 'on';
                plot(app.UIAxes2,app.t, -app.Vc+app.Vi-app.I.*app.R, 'r', 'linewidth',0.75);
                title(app.UIAxes2,'Napięcie na Cewce');
                xlabel(app.UIAxes2,'T(s)'); 
                ylabel(app.UIAxes2,'U(V)');
            end
            if (app.Knob_2.Value=="X(f)")
                app.UIAxes2.Visible = 'on';
                plot(app.UIAxes2,app.F, app.XL,app.F,app.XC,app.F, app.X);
                title(app.UIAxes2,'Charakterystyka częstotliwościowa');
                xlabel(app.UIAxes2,'f [Hz]'); 
                ylabel(app.UIAxes2,'X [Ohm]');
                legend(app.UIAxes2,'XL','XC','X');
                app.fSpinner.Enable='on';
            end
            if (app.Knob_3.Value=="Uc(T)")
                app.UIAxes3.Visible = 'on';
                plot(app.UIAxes3,app.t, app.Vc, 'r', 'linewidth',0.75);
                title(app.UIAxes3,'Napięcie na kondensatorze'); 
                xlabel(app.UIAxes3,'T(s)'); 
                ylabel(app.UIAxes3,'U(V)');
            end
            if (app.Knob_3.Value=="I(T)")
                app.UIAxes3.Visible = 'on';
                plot(app.UIAxes3,app.t, app.I, 'r', 'linewidth',0.75);
                title(app.UIAxes3,'Prąd w obwodzie'); 
                xlabel(app.UIAxes3,'T(s)'); 
                ylabel(app.UIAxes3,'I(A)');
            end
            if (app.Knob_3.Value=="Ur(T)")
                app.UIAxes3.Visible = 'on';
                plot(app.UIAxes3,app.t, app.I.*app.R, 'r', 'linewidth',0.75);
                title(app.UIAxes3,'Napięcie na Rezystorze'); 
                xlabel(app.UIAxes3,'T(s)'); 
                ylabel(app.UIAxes3,'U(V)');
            end
            if (app.Knob_3.Value=="Ul(T)")
                app.UIAxes3.Visible = 'on';
                plot(app.UIAxes3,app.t, -app.Vc+app.Vi-app.I.*app.R, 'r', 'linewidth',0.75);
                title(app.UIAxes3,'Napięcie na Cewce');
                xlabel(app.UIAxes3,'T(s)'); 
                ylabel(app.UIAxes3,'U(V)');
            end
            if (app.Knob_3.Value=="X(f)")
                app.UIAxes3.Visible = 'on';
                plot(app.UIAxes3,app.F, app.XL,app.F,app.XC,app.F, app.X);
                title(app.UIAxes3,'Charakterystyka częstotliwościowa');
                xlabel(app.UIAxes3,'f [Hz]'); 
                ylabel(app.UIAxes3,'X [Ohm]');
                legend(app.UIAxes3,'XL','XC','X');
                app.fSpinner.Enable='on';
            end
            if (app.Knob_4.Value=="Uc(T)")
                app.UIAxes4.Visible = 'on';
                plot(app.UIAxes4,app.t, app.Vc, 'r', 'linewidth',0.75);
                title(app.UIAxes4,'Napięcie na kondensatorze'); 
                xlabel(app.UIAxes4,'T(s)'); 
                ylabel(app.UIAxes4,'U(V)');
            end
            if (app.Knob_4.Value=="I(T)")
                app.UIAxes4.Visible = 'on';
                plot(app.UIAxes4,app.t, app.I, 'r', 'linewidth',0.75);
                title(app.UIAxes4,'Prąd w obwodzie'); 
                xlabel(app.UIAxes4,'T(s)'); 
                ylabel(app.UIAxes4,'I(A)');
            end
            if (app.Knob_4.Value=="Ur(T)")
                app.UIAxes4.Visible = 'on';
                plot(app.UIAxes4,app.t, app.I.*app.R, 'r', 'linewidth',0.75);
                title(app.UIAxes4,'Napięcie na Rezystorze'); 
                xlabel(app.UIAxes4,'T(s)'); 
                ylabel(app.UIAxes4,'U(V)');
            end
            if (app.Knob_4.Value=="Ul(T)")
                app.UIAxes4.Visible = 'on';
                plot(app.UIAxes4,app.t, -app.Vc+app.Vi-app.I.*app.R, 'r', 'linewidth',0.75);
                title(app.UIAxes4,'Napięcie na Cewce');
                xlabel(app.UIAxes4,'T(s)'); 
                ylabel(app.UIAxes4,'U(V)');
            end
            if (app.Knob_4.Value=="X(f)")
                app.UIAxes4.Visible = 'on';
                plot(app.UIAxes4,app.F, app.XL,app.F,app.XC,app.F, app.X);
                title(app.UIAxes4,'Charakterystyka częstotliwościowa');
                xlabel(app.UIAxes4,'f [Hz]'); 
                ylabel(app.UIAxes4,'X [Ohm]');
                legend(app.UIAxes4,'XL','XC','X');
                app.fSpinner.Enable='on';
            end
            app.RSpinner.Enable='on';
                app.LSpinner.Enable='on';
                app.CSpinner.Enable='on';
                app.VinSpinner.Enable='on';
                app.TSpinner.Enable='on';
                app.MakeplotsButton.Enable = 'off';
                app.Knob.Enable = 'off';
                app.Knob_2.Enable = 'off';
                app.Knob_3.Enable = 'off';
                app.Knob_4.Enable = 'off';
                app.DrawplotsButton.Enable = 'off';
                app.Label_2.Text = 'Narysowano wykresy możesz zmienić wartości parametrów';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '3')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '2')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, '4')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.MakeplotsButton = uibutton(app.UIFigure, 'push');
            app.MakeplotsButton.ButtonPushedFcn = createCallbackFcn(app, @MakeplotsButtonPushed, true);
            app.MakeplotsButton.Text = 'Make plots';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'RLC_series_circuit_v1.svg.png';
            app.RSpinnerLabel = uilabel(app.UIFigure);
            app.RSpinnerLabel.Text = 'R';
            app.RSpinner = uispinner(app.UIFigure);
            app.RSpinner.Limits = [0 1000];
            app.RSpinner.ValueChangedFcn = createCallbackFcn(app, @RSpinnerValueChanged, true);
            app.LSpinnerLabel = uilabel(app.UIFigure);
            app.LSpinnerLabel.Text = 'L';
            app.LSpinner = uispinner(app.UIFigure);
            app.LSpinner.Limits = [0 1000];
            app.LSpinner.ValueChangedFcn = createCallbackFcn(app, @LSpinnerValueChanged, true);
            app.CSpinnerLabel = uilabel(app.UIFigure);
            app.CSpinnerLabel.Text = 'C';
            app.CSpinner = uispinner(app.UIFigure);
            app.CSpinner.Limits = [0 1000];
            app.CSpinner.ValueChangedFcn = createCallbackFcn(app, @CSpinnerValueChanged, true);
            app.VinSpinnerLabel = uilabel(app.UIFigure);
            app.VinSpinnerLabel.Text = {'Vin'; ''};
            app.VinSpinner = uispinner(app.UIFigure);
            app.VinSpinner.Limits = [0 1000];
            app.VinSpinner.ValueChangedFcn = createCallbackFcn(app, @VinSpinnerValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob.Items = {'Off', 'Uc(T)', 'I(T)', 'Ur(T)', 'Ul(T)', 'X(f)'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob_2Label = uilabel(app.UIFigure);
            app.Knob_2Label.Text = '2';
            app.Knob_2 = uiknob(app.UIFigure, 'discrete');
            app.Knob_2.Items = {'Off', 'Uc(T)', 'I(T)', 'Ur(T)', 'Ul(T)', 'X(f)'};
            app.Knob_2.ValueChangedFcn = createCallbackFcn(app, @Knob_2ValueChanged, true);
            app.Knob_3Label = uilabel(app.UIFigure);
            app.Knob_3Label.Text = '3';
            app.Knob_3 = uiknob(app.UIFigure, 'discrete');
            app.Knob_3.Items = {'Off', 'Uc(T)', 'I(T)', 'Ur(T)', 'Ul(T)', 'X(f)'};
            app.Knob_3.ValueChangedFcn = createCallbackFcn(app, @Knob_3ValueChanged, true);
            app.Knob_4Label = uilabel(app.UIFigure);
            app.Knob_4Label.Text = '4';
            app.Knob_4 = uiknob(app.UIFigure, 'discrete');
            app.Knob_4.Items = {'Off', 'Uc(T)', 'I(T)', 'Ur(T)', 'Ul(T)', 'X(f)'};
            app.Knob_4.ValueChangedFcn = createCallbackFcn(app, @Knob_4ValueChanged, true);
            app.TSpinnerLabel = uilabel(app.UIFigure);
            app.TSpinnerLabel.Text = 'T';
            app.TSpinner = uispinner(app.UIFigure);
            app.TSpinner.Limits = [0 1000];
            app.TSpinner.ValueChangedFcn = createCallbackFcn(app, @TSpinnerValueChanged, true);
            app.fSpinnerLabel = uilabel(app.UIFigure);
            app.fSpinnerLabel.Text = 'f';
            app.fSpinner = uispinner(app.UIFigure);
            app.fSpinner.Limits = [0 1000];
            app.fSpinner.ValueChangedFcn = createCallbackFcn(app, @fSpinnerValueChanged, true);
            app.DrawplotsButton = uibutton(app.UIFigure, 'push');
            app.DrawplotsButton.ButtonPushedFcn = createCallbackFcn(app, @DrawplotsButtonPushed, true);
            app.DrawplotsButton.Text = 'Draw plots';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Ω';
            app.mHLabel = uilabel(app.UIFigure);
            app.mHLabel.Text = 'mH';
            app.FLabel = uilabel(app.UIFigure);
            app.FLabel.Text = 'µF';
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = 'ms';
            app.HZLabel = uilabel(app.UIFigure);
            app.HZLabel.Text = 'HZ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end